classdef LEDPlacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LED_placement                 matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LEDlayoutPanel                matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        CircularlayoutPanel           matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DiameterEditFieldLabel        matlab.ui.control.Label
        DiameterEditField             matlab.ui.control.NumericEditField
        RectangularlayoutPanel        matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        xLabel                        matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        SidesizeEditField_2Label      matlab.ui.control.Label
        SidesizeEditField_2           matlab.ui.control.NumericEditField
        SidesizeEditField             matlab.ui.control.NumericEditField
        CustomlayoutPanel             matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        Createamatrixof0and1Label     matlab.ui.control.Label
        Createamatrixof0and1TextArea  matlab.ui.control.TextArea
        CenterLEDPanel                matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        CenterLEDEditField2           matlab.ui.control.NumericEditField
        CenterLEDEditField            matlab.ui.control.NumericEditField
        yLabel_2                      matlab.ui.control.Label
        xLabel_2                      matlab.ui.control.Label
        LoadlayoutButton              matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        legendLabel                   matlab.ui.control.Label
        SetthislayoutButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        GUIApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.GUIApp = mainapp;
            
            try
                LEDs=evalin('base','LEDs');
            catch
                LEDs=[];
            end
            
            if ~isempty(LEDs)
                [~,LEDs2,n1,n2] = LEDMatrix(5,0,0,0,0,LEDs);            
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.UIAxes);
                assignin('base','LEDs_tmp',LEDs)
            end
            app.DiameterEditField.Value = app.GUIApp.DEditField.Value;
            app.SidesizeEditField_2.Value = app.GUIApp.xEditField.Value;
            app.SidesizeEditField.Value = app.GUIApp.yEditField.Value;
            app.CenterLEDEditField2.Value = app.GUIApp.cxEditField.Value;
            app.CenterLEDEditField.Value = app.GUIApp.cyEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LED_placement.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {267, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            value = app.DiameterEditField.Value;
            
            [LEDs,LEDs2,n1,n2] = LEDMatrix(1,value,0);

            [valueY,valueX] = find(LEDs == 2);
            app.CenterLEDEditField2.Value = valueX;
            app.CenterLEDEditField.Value = valueY;               

            app.SidesizeEditField.Value = 0;
            app.SidesizeEditField_2.Value = 0;
            app.Createamatrixof0and1TextArea.Value = '';
%             app.TypeEditField = '1';
            
%             [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, 1, 1);
%             assignin('base','imageColOrder',imageColOrder);                
%             imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent', app.GUIApp.UIAxes_3);
%             colormap(app.GUIApp.UIAxes_3,colorMap)
%             
%             [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
%             assignin('base','LEDsUsed',LEDsUsed);
%             imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
%             
            assignin('base','LEDs_tmp',LEDs);
            imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.UIAxes);
%             
%             [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%             imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%             colormap(app.GUIApp.UIAxes_2,colorMap)
        end

        % Value changed function: SidesizeEditField_2
        function SidesizeEditField_2ValueChanged(app, event)
            value = app.SidesizeEditField.Value;
            value2 = app.SidesizeEditField_2.Value;           
            
            if value > 0 && value2 > 0
                [LEDs,LEDs2,n1,n2] = LEDMatrix(2,value,value2);
                [valueY,valueX] = find(LEDs == 2);
                app.CenterLEDEditField2.Value = valueX;
                app.CenterLEDEditField.Value = valueY; 
%                 app.TypeEditField = '2';
                assignin('base','LEDs_tmp',LEDs);
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2,'Parent',app.UIAxes);


%                 [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, 1, 1);
%                 assignin('base','imageColOrder',imageColOrder);                
%                 imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent', app.GUIApp.UIAxes_3);
%                 colormap(app.GUIApp.UIAxes_3,colorMap)
%                 
%                 [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
%                 assignin('base','LEDsUsed',LEDsUsed);
%                 imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
%                 
                app.DiameterEditField.Value = 0;
                app.Createamatrixof0and1TextArea.Value = '';
                
%                 [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%                 imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%                 colormap(app.GUIApp.UIAxes_2,colorMap)
            end
        end

        % Value changed function: SidesizeEditField
        function SidesizeEditFieldValueChanged(app, event)
            value = app.SidesizeEditField.Value;
            value2 = app.SidesizeEditField_2.Value;           
            
            if value > 0 && value2 > 0
                [LEDs,LEDs2,n1,n2] = LEDMatrix(2,value,value2);
                [valueY,valueX] = find(LEDs == 2);
                app.CenterLEDEditField2.Value = valueX;
                app.CenterLEDEditField.Value = valueY; 
%                 app.TypeEditField = 2;
                assignin('base','LEDs_tmp',LEDs);
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2,'Parent',app.UIAxes);


%                 [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, 1, 1);
%                 assignin('base','imageColOrder',imageColOrder);                
%                 imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent', app.GUIApp.UIAxes_3);
%                 colormap(app.GUIApp.UIAxes_3,colorMap)
%                 
%                 [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
%                 assignin('base','LEDsUsed',LEDsUsed);
%                 imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
%                 
                app.DiameterEditField.Value = 0;
                app.Createamatrixof0and1TextArea.Value = '';
                
%                 [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%                 imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%                 colormap(app.GUIApp.UIAxes_2,colorMap)
            end
        end

        % Value changed function: Createamatrixof0and1TextArea
        function Createamatrixof0and1TextAreaValueChanged(app, event)
            value = app.Createamatrixof0and1TextArea.Value;
            [LEDs,LEDs2,n1,n2] = LEDMatrix(3,value,0);
            assignin('base','LEDs_tmp',LEDs);
            imagesc(0.5:n1+1,0.5:n2+1,LEDs2,'Parent',app.UIAxes,[-1 2]);
            
%             [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, 1, 1);
%             assignin('base','imageColOrder',imageColOrder);                
%             imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent', app.GUIApp.UIAxes_3);
%             colormap(app.GUIApp.UIAxes_3,colorMap)
%             
%             [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
%             assignin('base','LEDsUsed',LEDsUsed);
%             imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
                
            app.DiameterEditField.Value = 0;
            app.SidesizeEditField.Value = 0;
            app.SidesizeEditField_2.Value = 0;
            
%             [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%             imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%             colormap(app.GUIApp.UIAxes_2,colorMap)
        end

        % Button pushed function: LoadlayoutButton
        function LoadlayoutButtonPushed(app, event)
            others = evalin('base','others');    
            if exist(others.lastloadLEDlayoutpath, 'dir')
                [file, path] = uigetfile(fullfile(others.lastloadLEDlayoutpath,'*.mat'));
            else
                [file, path] = uigetfile('*.mat');
            end
            figure(app.GUIApp.FPM_app)
            figure(app.LED_placement)
            if path ~= 0    
                path = ChangeSlash(path);
                others.lastloadLEDlayoutpath = string(path);
                assignin('base','others',others);
                save('./initialization2.mat','others');
                
                LEDs_path = fullfile(path,file);
                LEDs_path = ChangeSlash(LEDs_path);
    %             assignin('base','amplitude_path',amplitude_path);
    %             amplitude = mean(double(imread(amplitude_path)),3);    
                tmp = load(LEDs_path);
                LEDs = tmp.LEDs;
                
                [LEDs,LEDs2,n1,n2] = LEDMatrix(5,[],[],[],[],LEDs);
                
                if max(max(LEDs == 2))
                    [valueY,valueX] = find(LEDs == 2);
                    app.CenterLEDEditField2.Value = valueX;
                    app.CenterLEDEditField.Value = valueY;               
                else
                    app.CenterLEDEditField2.Value = 0;
                    app.CenterLEDEditField.Value = 0;       
                end
                
                app.DiameterEditField.Value = 0;
                app.SidesizeEditField.Value = 0;
                app.SidesizeEditField_2.Value = 0;
                app.Createamatrixof0and1TextArea.Value = '';
                
                assignin('base','LEDs_tmp',LEDs);
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.UIAxes,[-1 2]);            
                
%                 [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, 1, 1);
%                 assignin('base','imageColOrder',imageColOrder);                
%                 imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent', app.GUIApp.UIAxes_3);
%                 colormap(app.GUIApp.UIAxes_3,colorMap)
%                 
%                 [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
%                 assignin('base','LEDsUsed',LEDsUsed);
%                 imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
%                 
%                 [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%                 imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%                 colormap(app.GUIApp.UIAxes_2,colorMap)
            end            
        end

        % Value changed function: CenterLEDEditField2
        function CenterLEDEditField2ValueChanged(app, event)
            valueX = app.CenterLEDEditField.Value;
            valueY = app.CenterLEDEditField2.Value;

            if valueX > 0 && valueY > 0
                LEDs = evalin('base', 'LEDs_tmp');
                [LEDs,LEDs2,n1,n2] = LEDMatrix(4,0,0,valueX,valueY,LEDs);
                assignin('base','LEDs_tmp',LEDs);
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2,'Parent',app.UIAxes);
                
%                 [imageColOrder] = ImgColOrder(LEDs, 1, 1);
%                 [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%                 imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%                 colormap(app.GUIApp.UIAxes_2,colorMap)
            end
        end

        % Value changed function: CenterLEDEditField
        function CenterLEDEditFieldValueChanged(app, event)
            valueX = app.CenterLEDEditField.Value;
            valueY = app.CenterLEDEditField2.Value;

            if valueX > 0 && valueY > 0
                LEDs = evalin('base', 'LEDs_tmp');
                [LEDs,LEDs2,n1,n2] = LEDMatrix(4,0,0,valueX,valueY,LEDs);
                assignin('base','LEDs_tmp',LEDs);
                imagesc(0.5:n1+1,0.5:n2+1,LEDs2,'Parent',app.UIAxes);
%                 [imageColOrder] = ImgColOrder(LEDs, 1, 1);
%                 [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
%                 imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2);
%                 colormap(app.GUIApp.UIAxes_2,colorMap)
            end
        end

        % Button pushed function: SetthislayoutButton
        function SetthislayoutButtonPushed(app, event)
            LEDs = evalin('base','LEDs_tmp');
            imageColOrder = evalin('base','imageColOrder');
            if app.GUIApp.typeEditField.Value > 0 && app.GUIApp.typeEditField.Value < 5
                [y,x] = find(imageColOrder==1);
                [ny,nx] = size(imageColOrder);
                if y < ny/2
                    if x<nx/2
                        imStart = 1;
                    else
                        imStart = 2;
                    end
                else
                    if x<nx/2
                        imStart = 3;
                    else
                        imStart = 4;
                    end
                end
                [imageColOrder,displayingOrder,n1,n2,colorMap] = ImgColOrder(LEDs, imStart, app.GUIApp.typeEditField.Value);
            elseif app.GUIApp.typeEditField.Value > 4 && app.GUIApp.typeEditField.Value < 7
                if app.GUIApp.typeEditField.Value == 5 % clockwise
                    type = 1;
                elseif app.GUIApp.typeEditField.Value == 6 % counter-clockwise
                    type = -1;
                end
                imageColOrder = createSpiral(LEDs,type,0);
                [~,displayingOrder,n1,n2,colorMap] = ImgColOrder(imageColOrder, 1, 0);
            end
            assignin('base','imageColOrder',imageColOrder);
            imagesc(0.5:n1+1,0.5:n2+1,displayingOrder,'Parent',app.GUIApp.UIAxes_3);
            colormap(app.GUIApp.UIAxes_3,colorMap)
            
            [LEDsUsed,displ,n1,n2] = Used_LEDs(LEDs,0,1);
            assignin('base','LEDsUsed',LEDsUsed);
            imagesc(0.5:n1+1,0.5:n2+1,displ, 'Parent',app.GUIApp.UIAxes_4, [-1 2]);
            %
            assignin('base','LEDs',LEDs);
            
            [~,LEDs2,n1,n2,colorMap] = LEDMatrix(5,0,0,0,0,LEDs,imageColOrder,app.GUIApp.PreviewimageEditField.Value);
            imagesc(0.5:n1+1,0.5:n2+1,LEDs2, 'Parent',app.GUIApp.UIAxes_2, [-1 3]);
            colormap(app.GUIApp.UIAxes_2,colorMap);% colorbar(app.GUIApp.UIAxes_2);
            
            app.GUIApp.DEditField.Value = app.DiameterEditField.Value;
            app.GUIApp.xEditField.Value = app.SidesizeEditField_2.Value;
            app.GUIApp.yEditField.Value = app.SidesizeEditField.Value;
            app.GUIApp.cxEditField.Value = app.CenterLEDEditField2.Value;
            app.GUIApp.cyEditField.Value = app.CenterLEDEditField.Value;
        end

        % Close request function: LED_placement
        function LED_placementCloseRequest(app, event)
            app.GUIApp.ChangeLEDplacementButton.Enable = 'on';    
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LED_placement = uifigure('Visible', 'off');
            app.LED_placement.Name = 'LED placement';
            app.LED_placement.CloseRequestFcn = createCallbackFcn(app, @LED_placementCloseRequest, true);
            app.LED_placement.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LED_placement);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LEDlayoutPanel = uipanel(app.LeftPanel);
            app.LEDlayoutPanel.Title = 'LED layout';
            app.GridLayout3 = uigridlayout(app.LEDlayoutPanel);
            app.CircularlayoutPanel = uipanel(app.GridLayout3);
            app.CircularlayoutPanel.Tooltip = {'Change Diameter parameter to create circular LED array'};
            app.CircularlayoutPanel.Title = 'Circular layout';
            app.GridLayout4 = uigridlayout(app.CircularlayoutPanel);
            app.DiameterEditFieldLabel = uilabel(app.GridLayout4);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.RectangularlayoutPanel = uipanel(app.GridLayout3);
            app.RectangularlayoutPanel.Tooltip = {'Change both x and y parameters to create x by y rectangular LEDs array'};
            app.RectangularlayoutPanel.Title = 'Rectangular layout';
            app.GridLayout5 = uigridlayout(app.RectangularlayoutPanel);
            app.xLabel = uilabel(app.GridLayout5);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.GridLayout5);
            app.yLabel.Text = ' y';
            app.SidesizeEditField_2Label = uilabel(app.GridLayout5);
            app.SidesizeEditField_2Label.Text = 'Side size';
            app.SidesizeEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.SidesizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @SidesizeEditField_2ValueChanged, true);
            app.SidesizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SidesizeEditField.ValueChangedFcn = createCallbackFcn(app, @SidesizeEditFieldValueChanged, true);
            app.CustomlayoutPanel = uipanel(app.GridLayout3);
            app.CustomlayoutPanel.Tooltip = {'Put 1 in places where there are LEDs and 0 where there are not. There need to be the same number of digits in every row'};
            app.CustomlayoutPanel.Title = 'Custom layout';
            app.GridLayout6 = uigridlayout(app.CustomlayoutPanel);
            app.Createamatrixof0and1Label = uilabel(app.GridLayout6);
            app.Createamatrixof0and1Label.Text = {'Create '; 'a matrix'; 'of '; '0 and 1 '};
            app.Createamatrixof0and1TextArea = uitextarea(app.GridLayout6);
            app.Createamatrixof0and1TextArea.ValueChangedFcn = createCallbackFcn(app, @Createamatrixof0and1TextAreaValueChanged, true);
            app.CenterLEDPanel = uipanel(app.GridLayout3);
            app.CenterLEDPanel.Tooltip = {'Choose center LED (with 0 deg illumination angle)'};
            app.CenterLEDPanel.Title = 'Center LED';
            app.GridLayout7 = uigridlayout(app.CenterLEDPanel);
            app.CenterLEDEditField2 = uieditfield(app.GridLayout7, 'numeric');
            app.CenterLEDEditField2.ValueChangedFcn = createCallbackFcn(app, @CenterLEDEditField2ValueChanged, true);
            app.CenterLEDEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CenterLEDEditField.ValueChangedFcn = createCallbackFcn(app, @CenterLEDEditFieldValueChanged, true);
            app.yLabel_2 = uilabel(app.GridLayout7);
            app.yLabel_2.Text = 'y';
            app.xLabel_2 = uilabel(app.GridLayout7);
            app.xLabel_2.Text = 'x';
            app.LoadlayoutButton = uibutton(app.GridLayout3, 'push');
            app.LoadlayoutButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlayoutButtonPushed, true);
            app.LoadlayoutButton.Tooltip = {'Load custom created LED layout (.mat file)'};
            app.LoadlayoutButton.Text = 'Load layout';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'LED layout')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.legendLabel = uilabel(app.RightPanel);
            app.legendLabel.Tooltip = {'Yellow -  central LED'; 'Green -  LEDs used to collect images'; 'Blue - empty space or not used LEDs'};
            app.legendLabel.Text = '[legend]';
            app.SetthislayoutButton = uibutton(app.RightPanel, 'push');
            app.SetthislayoutButton.ButtonPushedFcn = createCallbackFcn(app, @SetthislayoutButtonPushed, true);
            app.SetthislayoutButton.Text = 'Set this layout';
            % Show the figure after all components are created
            app.LED_placement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDPlacement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LED_placement)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LED_placement)
        end
    end
end